classdef Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Menu                  matlab.ui.container.Menu
        Menu2                 matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        XAxisDropDownLabel    matlab.ui.control.Label
        XAxisDropDown         matlab.ui.control.DropDown
        YAxisDropDownLabel    matlab.ui.control.Label
        YAxisDropDown         matlab.ui.control.DropDown
        YAxisDropDown_2Label  matlab.ui.control.Label
        YAxisDropDown_2       matlab.ui.control.DropDown
        XAxisDropDown_2Label  matlab.ui.control.Label
        XAxisDropDown_2       matlab.ui.control.DropDown
        CheckBox              matlab.ui.control.CheckBox
        Update_Plot           matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        UIAxes2               matlab.ui.control.UIAxes
        Tab2                  matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        Tab3                  matlab.ui.container.Tab
        UITable               matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data; % Description
        Instruments;
        Lakeshore_found =0;
        VQM_found = 0;
        MFC_found = 0;
    end
    
    methods (Access = private)
        
        function Parse_Instruments(app)
            app.Instruments =app.Data.Properties.VariableNames;
            app.Instruments(1:2) = [];
            switch string(app.Instruments)
                case 'Temperature_A'
                    app.Lakeshore_found = 1;
                case 'Log_Pressure'
                    app.VQM_found = 1;
                case 'MFC'
                    app.MFC = 1;
            end
        end
        
        function Fill_Y_Data(app)
            
            for idx = 1:length(app.Instruments)
                if strcmp(string(app.Instruments(idx)), 'Temperature_A') == 1
                    app.YAxisDropDown.Items(idx) = {'Temperature A'};
                    
           
                elseif strcmp(string(app.Instruments(idx)), 'Temperature_C') == 1
                    app.YAxisDropDown.Items(idx) = {'Temperature C'};
                elseif strcmp(string(app.Instruments(idx)), 'Log_Pressure') == 1
                    app.YAxisDropDown.Items(idx) = {'Pressure'};
                
                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 0;
            type = '../*.dat';
            [name, path] =uigetfile(type);
            disp(name)
            disp(path)
            app.Data = readtable([path, name]);
           
            app.UITable.Data = app.Data;
            for idx =1:length(app.Data.Properties.VariableNames)
             app.UITable.ColumnName(idx) =  app.Data.Properties.VariableNames(idx);
                
            end
            app.XAxisDropDown.Items = {'Full Time','Custom Range'};
            app.Parse_Instruments();
            app.Fill_Y_Data();
             app.UIFigure.Visible = 1;
        end

        % Button pushed function: Update_Plot
        function Update_PlotButtonPushed(app, event)
            
            app.UIAxes, app.XAxisDropDown.Value
            
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {448, 448};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.XAxisDropDownLabel = uilabel(app.LeftPanel);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.LeftPanel);
            app.YAxisDropDownLabel = uilabel(app.LeftPanel);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.LeftPanel);
            app.YAxisDropDown_2Label = uilabel(app.LeftPanel);
            app.YAxisDropDown_2Label.Text = 'Y Axis';
            app.YAxisDropDown_2 = uidropdown(app.LeftPanel);
            app.XAxisDropDown_2Label = uilabel(app.LeftPanel);
            app.XAxisDropDown_2Label.Text = 'X Axis';
            app.XAxisDropDown_2 = uidropdown(app.LeftPanel);
            app.CheckBox = uicheckbox(app.LeftPanel);
            app.Update_Plot = uibutton(app.LeftPanel, 'push');
            app.Update_Plot.ButtonPushedFcn = createCallbackFcn(app, @Update_PlotButtonPushed, true);
            app.Update_Plot.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UITable = uitable(app.Tab3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end